classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GraphTab                       matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        ReportTab                      matlab.ui.container.Tab
        WrongWordsListBox              matlab.ui.control.ListBox
        WrongWordsLabel                matlab.ui.control.Label
        CorrectWordsListBox            matlab.ui.control.ListBox
        CorrectWordsListBoxLabel       matlab.ui.control.Label
        xxLabel                        matlab.ui.control.Label
        RecordingLamp_2                matlab.ui.control.Lamp
        RecordingLamp_2Label           matlab.ui.control.Label
        RecordingLamp                  matlab.ui.control.Lamp
        RecordingLampLabel             matlab.ui.control.Label
        TFLabel                        matlab.ui.control.Label
        GenderLabel                    matlab.ui.control.Label
        AccuracyGauge                  matlab.ui.control.SemicircularGauge
        AccuracyGaugeLabel             matlab.ui.control.Label
        TestButton_2                   matlab.ui.control.Button
        ReportButton                   matlab.ui.control.Button
        TestButton                     matlab.ui.control.Button
        ClickReporttogenerateresultsforyourrecordsLabel  matlab.ui.control.Label
        RecordButton_2                 matlab.ui.control.Button
        ClickRecordandProunounceselectedwordLabel  matlab.ui.control.Label
        WordslistDropDown              matlab.ui.control.DropDown
        WordslistDropDownLabel         matlab.ui.control.Label
        ClickRecordandProunounceLabel  matlab.ui.control.Label
        ProunounceTesterLabel          matlab.ui.control.Label
        RecordButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        words = ["ذا", "ثا", "ثا", "سا", "نذير", "نظير", "تين", "طين", "دل", "ضل", "عد", "عض", "صامد", "صامت", "ضن", "ظن", "لمس", "لمز", "مسح", "مسخ", "مصير", "مسير", "ذل", "زل", "زفر", "ظفر", "خير", "غير", "قلب", "كلب", "خائن", "كائن", "مسك", "مسخ", "علم", "ألم", "حمزة", "همزة", "حالة", "هالة", "حرم", "هرم", "حامل", "خامل", "هزم", "حزم"]
        refUserRecord %stores the user record for the word "الحمد لله"
        refUserRecord_MFCC 
        refUserRecord_Gender
        
        
        wordUserRecord
        WordUserRecord_MFCC
        currentSelWord ='1'; %to help knowing the current selected word
        
        recordedWords = zeros(1,46); % to know which words are recorded (when recording a word, its corresponding index will be 1)
        recordedWordsRW = zeros(1,46); % to know whick words are right and which are wrong, 1 means its right
        %when calculating the accuracy we divide number of ones of recordedWordsRW by number of ones of recordedWords
        
        accuracy 
        
        correctWordsList
        wrongWordsList
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordingLamp.Color = 'g';
            output = userRecord;
            output = silence_removal(output,0,16000)';
            app.refUserRecord = output;
            app.RecordingLamp.Color = 'w';
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            %MFCC
            app.refUserRecord_MFCC = mfcc(app.refUserRecord,16000); %mfcc for the ref "الحمد لله"
            avg1 = mean(app.refUserRecord_MFCC);
            app.refUserRecord_MFCC = app.refUserRecord_MFCC - avg1;
            app.refUserRecord_MFCC = app.refUserRecord_MFCC';
            
            mfccMaleRef = load("MFCCM\G03S3M22M_MFCCP24W1R.mat");
            mfccChildRef = load("MFCCC\G03S4C11W_MFCCP24W1R.mat");
            mfccFemaleRef = load("MFCCF\G03S3F33M_MFCCP24W1R.mat");
            
            %DTW
            DTW(1) = dtw(app.refUserRecord_MFCC,mfccMaleRef.MFCC_data');
            DTW(2) = dtw(app.refUserRecord_MFCC,mfccFemaleRef.MFCC_data');
            DTW(3) = dtw(app.refUserRecord_MFCC,mfccChildRef.MFCC_data');
            
            [~, decision] = min(DTW);
            
            if decision == 1
                app.refUserRecord_Gender = "Male";
                app.GenderLabel.FontColor = [1.00,0.41,0.16];
                app.GenderLabel.Text = "Male";
                
            elseif decision ==2
                app.refUserRecord_Gender = "Female";
                app.GenderLabel.FontColor = [1.00,0.00,1.00];
                app.GenderLabel.Text = "Female";
            else
                app.refUserRecord_Gender = "Child";
                app.GenderLabel.FontColor = [0.07,0.62,1.00];
                app.GenderLabel.Text = "Child";

            end


        end

        % Value changed function: WordslistDropDown
        function WordslistDropDownValueChanged(app, event)
            
            app.currentSelWord = app.WordslistDropDown.Value;
            
        end

        % Button pushed function: RecordButton_2
        function RecordButton_2Pushed(app, event)
            
            app.recordedWords(str2double(app.WordslistDropDown.Value)) = 1; % to know that this word is recorded 
            
            app.RecordingLamp_2.Color ='g';
            
            output = userRecord;
            output = silence_removal(output,0,16000)';
            app.wordUserRecord= output;
            
            app.RecordingLamp_2.Color ='w';
            
        end

        % Button pushed function: TestButton_2
        function TestButton_2Pushed(app, event)
            %word mfcc
            app.WordUserRecord_MFCC = mfcc(app.wordUserRecord,16000);
            avg1 = mean(app.WordUserRecord_MFCC);
            app.WordUserRecord_MFCC = app.WordUserRecord_MFCC - avg1;
            app.WordUserRecord_MFCC = app.WordUserRecord_MFCC';
            %loading MFCC files
            if app.refUserRecord_Gender == "Male"
                MFCC_Gender = dir("MFCCM\*.mat");
                MFCC_Gender_TH = readtable("Thresholds\Male_TH.xlsx",'Range','B2');
            elseif app.refUserRecord_Gender == "Female"
                MFCC_Gender = dir("MFCCF\*.mat");
                MFCC_Gender_TH = readtable("Thresholds\Female_TH.xlsx",'Range','B2');
            else
                MFCC_Gender = dir("MFCCC\*.mat");
                MFCC_Gender_TH = readtable("Thresholds\Child_TH.xlsx",'Range','B2');
            end
            
            %getting corresponding MFCC from data base
            MFCC_db = load(MFCC_Gender(str2double(app.currentSelWord)).folder +"\"+ MFCC_Gender(str2double(app.currentSelWord)).name);
            
            %Calculating DTW
            [DTW,xi,yi] = dtw(app.WordUserRecord_MFCC,MFCC_db.MFCC_data');
            
            %Th
            P =1;
            W =1;
            for i=1:str2double(app.currentSelWord)-1
              if W ==2
                  W = 1;
                  P = P+1;
              else
                  W = W+1;
              end
            end
            TH = table2array(MFCC_Gender_TH(P,W));
            
            %Comparing DTW with threatholds
            if DTW > TH
               app.TFLabel.FontColor = 'r';
               app.TFLabel.Text = 'Wrong'; 
               
               app.recordedWordsRW(str2double(app.WordslistDropDown.Value)) = 0;
               
            else
                app.TFLabel.FontColor = 'g'; 
                app.TFLabel.Text = 'Right';
                
                app.recordedWordsRW(str2double(app.WordslistDropDown.Value)) = 1; 
            end
    
            %plotting , we have xi,yi output of the DTW
            userxi = app.WordUserRecord_MFCC(:,xi);
            var = MFCC_db.MFCC_data';
            datayi = var(:,yi);
            xaxis = 1:length(datayi);
            yaxis =[];
            
            for k=1:1:length(datayi)
               yaxis(k) = dtw(userxi(:,k),datayi(:,k));
            end
            
            plot(app.UIAxes,xaxis,yaxis);


        end

        % Callback function
        function RESETButtonPushed(app, event)
            
        clear;
            
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
            app.accuracy = (sum(app.recordedWordsRW)/sum(app.recordedWords))*100;
            app.AccuracyGauge.Value = app.accuracy;
            app.xxLabel.Text = num2str(app.accuracy) + " %";
            
            % correct words list & wrond words list
            cwl = strings(0);
            rwl =strings(0);
            for k=1:46
               if(app.recordedWordsRW(k) == 1)
                   cwl = [cwl app.words(k)]; 
               else
                   if(app.recordedWords(k) == 1)
                     rwl = [rwl app.words(k)];  
                   end
               end 
            end
            app.WrongWordsListBox.Items = rwl;
            app.CorrectWordsListBox.Items= cwl;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.ProunounceTesterLabel = uilabel(app.UIFigure);
            app.ProunounceTesterLabel.Text = 'Prounounce Tester';
            app.ClickRecordandProunounceLabel = uilabel(app.UIFigure);
            app.ClickRecordandProunounceLabel.Text = 'Click Record and Prounounce "الحمد لله"';
            app.WordslistDropDownLabel = uilabel(app.UIFigure);
            app.WordslistDropDownLabel.Text = 'Words list';
            app.WordslistDropDown = uidropdown(app.UIFigure);
            app.WordslistDropDown.Items = {'ذا', 'َثا', 'ثا', 'سا', 'نذير', 'نظير', 'تين', 'طين', 'دل', 'ضل', 'عد', 'عض', 'صامد', 'صامت', 'ضن', 'ظن', 'لمس', 'لمز', 'مسح', 'مسخ', 'مصير', 'مسير', 'ذل', 'زل', 'زفر', 'ظفر', 'خير', 'غير', 'قلب', 'كلب', 'خائن', 'كائن', 'مسك', 'مسخ', 'علم', 'ألم', 'حمزة', 'همزة', 'حالة', 'هالة', 'حرم', 'هرم', 'حامل', 'خامل', 'هزم', 'حزم'};
            app.WordslistDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46'};
            app.WordslistDropDown.ValueChangedFcn = createCallbackFcn(app, @WordslistDropDownValueChanged, true);
            app.WordslistDropDown.Value = '1';
            app.ClickRecordandProunounceselectedwordLabel = uilabel(app.UIFigure);
            app.ClickRecordandProunounceselectedwordLabel.Text = 'Click Record and Prounounce selected word';
            app.RecordButton_2 = uibutton(app.UIFigure, 'push');
            app.RecordButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecordButton_2Pushed, true);
            app.RecordButton_2.Text = 'Record';
            app.ClickReporttogenerateresultsforyourrecordsLabel = uilabel(app.UIFigure);
            app.ClickReporttogenerateresultsforyourrecordsLabel.Text = 'Click Report to generate results for your records';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.ReportButton = uibutton(app.UIFigure, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.TestButton_2 = uibutton(app.UIFigure, 'push');
            app.TestButton_2.ButtonPushedFcn = createCallbackFcn(app, @TestButton_2Pushed, true);
            app.TestButton_2.Text = 'Test';
            app.AccuracyGaugeLabel = uilabel(app.UIFigure);
            app.AccuracyGaugeLabel.Text = 'Accuracy';
            app.AccuracyGauge = uigauge(app.UIFigure, 'semicircular');
            app.GenderLabel = uilabel(app.UIFigure);
            app.GenderLabel.Text = 'Gender';
            app.TFLabel = uilabel(app.UIFigure);
            app.TFLabel.Text = 'T/F';
            app.RecordingLampLabel = uilabel(app.UIFigure);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.UIFigure);
            app.RecordingLamp_2Label = uilabel(app.UIFigure);
            app.RecordingLamp_2Label.Text = 'Recording';
            app.RecordingLamp_2 = uilamp(app.UIFigure);
            app.xxLabel = uilabel(app.UIFigure);
            app.xxLabel.Text = 'xx%';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frame number')
            ylabel(app.UIAxes, 'Local distance')
            zlabel(app.UIAxes, 'Z')
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.CorrectWordsListBoxLabel = uilabel(app.ReportTab);
            app.CorrectWordsListBoxLabel.Text = 'Correct Words';
            app.CorrectWordsListBox = uilistbox(app.ReportTab);
            app.CorrectWordsListBox.Items = {};
            app.CorrectWordsListBox.Value = {};
            app.WrongWordsLabel = uilabel(app.ReportTab);
            app.WrongWordsLabel.Text = 'Wrong Words   ';
            app.WrongWordsListBox = uilistbox(app.ReportTab);
            app.WrongWordsListBox.Items = {};
            app.WrongWordsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end